classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RemoveOutlierOptionDropdown     matlab.ui.control.DropDown
        FillOutlierOptionDropdown       matlab.ui.control.DropDown
        EXPORTDATABEFOREMVCButton       matlab.ui.control.Button
        TrackA_FC1                      matlab.ui.control.NumericEditField
        TrackA_FC2                      matlab.ui.control.NumericEditField
        FilterOrderDropdown             matlab.ui.control.DropDown
        FilterTypeDropdown              matlab.ui.control.DropDown
        EXPORTDATAAFTERMVCButton        matlab.ui.control.Button
        MVCNORMALISATIONButton          matlab.ui.control.Button
        PROCESSINGTRACKBButton          matlab.ui.control.Button
        PROCESSINGTRACKAButton          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RAWDATATab                      matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CH1Tab                          matlab.ui.container.Tab
        RawDataChannel1                 matlab.ui.control.UIAxes
        CH2Tab                          matlab.ui.container.Tab
        RawDataChannel2                 matlab.ui.control.UIAxes
        CH3Tab                          matlab.ui.container.Tab
        RawDataChannel3                 matlab.ui.control.UIAxes
        CH4Tab                          matlab.ui.container.Tab
        RawDataChannel4                 matlab.ui.control.UIAxes
        CH5Tab                          matlab.ui.container.Tab
        RawDataChannel5                 matlab.ui.control.UIAxes
        mVDATATab                       matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        CH1Tab_2                        matlab.ui.container.Tab
        MvDataChannel1                  matlab.ui.control.UIAxes
        CH2Tab_2                        matlab.ui.container.Tab
        MvDataChannel2                  matlab.ui.control.UIAxes
        CH3Tab_2                        matlab.ui.container.Tab
        MvDataChannel3                  matlab.ui.control.UIAxes
        CH4Tab_2                        matlab.ui.container.Tab
        MvDataChannel4                  matlab.ui.control.UIAxes
        CH5Tab_2                        matlab.ui.container.Tab
        MvDataChannel5                  matlab.ui.control.UIAxes
        FFTANALYSISTab                  matlab.ui.container.Tab
        TabGroup2_3                     matlab.ui.container.TabGroup
        CH1Tab_3                        matlab.ui.container.Tab
        FFTDataChannel1                 matlab.ui.control.UIAxes
        CH2Tab_3                        matlab.ui.container.Tab
        FFTDataChannel2                 matlab.ui.control.UIAxes
        CH3Tab_3                        matlab.ui.container.Tab
        FFTDataChannel3                 matlab.ui.control.UIAxes
        CH4Tab_3                        matlab.ui.container.Tab
        FFTDataChannel4                 matlab.ui.control.UIAxes
        CH5Tab_3                        matlab.ui.container.Tab
        FFTDataChannel5                 matlab.ui.control.UIAxes
        REMOVEOUTLIERTab                matlab.ui.container.Tab
        TabGroup2_4                     matlab.ui.container.TabGroup
        CH1Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel1             matlab.ui.control.UIAxes
        CH2Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel2             matlab.ui.control.UIAxes
        CH3Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel3             matlab.ui.control.UIAxes
        CH4Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel4             matlab.ui.control.UIAxes
        CH5Tab_4                        matlab.ui.container.Tab
        OutlierDataChannel5             matlab.ui.control.UIAxes
        PROCESSINGTRACKATab             matlab.ui.container.Tab
        TabGroup2_5                     matlab.ui.container.TabGroup
        CH1Tab_5                        matlab.ui.container.Tab
        TrackAchannel1                  matlab.ui.control.UIAxes
        CH2Tab_5                        matlab.ui.container.Tab
        TrackAchannel2                  matlab.ui.control.UIAxes
        CH3Tab_5                        matlab.ui.container.Tab
        TrackAchannel3                  matlab.ui.control.UIAxes
        CH4Tab_5                        matlab.ui.container.Tab
        TrackAchannel4                  matlab.ui.control.UIAxes
        CH5Tab_5                        matlab.ui.container.Tab
        TrackAchannel5                  matlab.ui.control.UIAxes
        PROCESSINGTRACKBTab             matlab.ui.container.Tab
        MVCNORMALISATIONTab             matlab.ui.container.Tab
        OutlierDeviationOptionDropdown  matlab.ui.control.DropDown
        REMOVEOUTLIERButton             matlab.ui.control.Button
        FFTButton                       matlab.ui.control.Button
        TOmVButton                      matlab.ui.control.Button
        mVDATALabel                     matlab.ui.control.Label
        TEXTFILEDATALabel               matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        READTXTFILEButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        mVDataMatrix % Description
        nSeq % Description
        FFTDataMatrix % Description
        rawChannelData % Description
        FilterType = 'hp'; % Description
        FilterOrder = 1; % Description
        Channel1FFTData % Description
        Channel2FFTData % Description
        Channel3FFTData % Description
        Channel4FFTData % Description
        Channel5FFTData % Description
        f % Description
        fillOutlierOption = "center" % Description
        removeOutlierOption = "median" % Description
        thresholdFactor = 1 % Description
        SettingsUsed = ["Here is a list of all the settings used:"]; % Description
        fs % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READTXTFILEButton
        function READTXTFILEButtonPushed(app, event)

[filename,path]=uigetfile("DATA/PP01/*.txt");
app.FilenameEditField.Value = filename;
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
loadedData = readmatrix(pathToFile);
%vars = {'nSeq','DI','CH1','CH2','CH3','CH4','CH5'};
%app.UITable.ColumnName = vars;
app.UITable.Data = loadedData;
fclose(fileID);
figure(app.UIFigure);

%STAP 2
app.rawChannelData = loadedData(:,3:end);
app.nSeq = loadedData(:,1);

channel1RawData = app.rawChannelData(:,1);
channel2RawData = app.rawChannelData(:,2);
channel3RawData = app.rawChannelData(:,3);
channel4RawData = app.rawChannelData(:,4);
channel5RawData = app.rawChannelData(:,5);

plot(app.RawDataChannel1,app.nSeq,channel1RawData);
plot(app.RawDataChannel2,app.nSeq,channel2RawData);
plot(app.RawDataChannel3,app.nSeq,channel3RawData);
plot(app.RawDataChannel4,app.nSeq,channel4RawData);
plot(app.RawDataChannel5,app.nSeq,channel5RawData);

if (height(app.SettingsUsed) == 1)
    app.SettingsUsed = [app.SettingsUsed; ("Import: " + pathToFile)];
end

app.TOmVButton.Enable = 'on';
app.mVDataMatrix = ((app.rawChannelData ./ (2^16)) - (0.5)) * 3;

        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;

        end

        % Button pushed function: TOmVButton
        function TOmVButtonPushed(app, event)
                
            app.UITable_2.Data = app.mVDataMatrix;

            channel1MvData = app.mVDataMatrix(:,1);
            channel2MvData = app.mVDataMatrix(:,2);
            channel3MvData = app.mVDataMatrix(:,3);
            channel4MvData = app.mVDataMatrix(:,4);
            channel5MvData = app.mVDataMatrix(:,5);

            plot(app.MvDataChannel1,app.nSeq,channel1MvData);
            plot(app.MvDataChannel2,app.nSeq,channel2MvData);
            plot(app.MvDataChannel3,app.nSeq,channel3MvData);
            plot(app.MvDataChannel4,app.nSeq,channel4MvData);
            plot(app.MvDataChannel5,app.nSeq,channel5MvData);

            if (height(app.SettingsUsed) == 2)
                app.SettingsUsed = [app.SettingsUsed; "Convert to mV"];
            end

            app.FFTButton.Enable = 'on';

        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            
            app.fs = 1000;
            L = length(app.nSeq);
            app.f = app.fs*(0:(L/2))/L;
                 
            app.FFTDataMatrix = fft(app.mVDataMatrix);
            
            app.Channel1FFTData = app.FFTDataMatrix(:,1);
            app.Channel2FFTData = app.FFTDataMatrix(:,2);
            app.Channel3FFTData = app.FFTDataMatrix(:,3);
            app.Channel4FFTData = app.FFTDataMatrix(:,4);
            app.Channel5FFTData = app.FFTDataMatrix(:,5);

            app.Channel1FFTData = abs(app.Channel1FFTData/L);
            app.Channel2FFTData = abs(app.Channel2FFTData/L);
            app.Channel3FFTData = abs(app.Channel3FFTData/L);
            app.Channel4FFTData = abs(app.Channel4FFTData/L);
            app.Channel5FFTData = abs(app.Channel5FFTData/L);
     
            app.Channel1FFTData = app.Channel1FFTData(1:L/2+1);
            app.Channel1FFTData(2:end-1) = 2*app.Channel1FFTData(2:end-1);
            app.Channel2FFTData = app.Channel2FFTData(1:L/2+1);
            app.Channel2FFTData(2:end-1) = 2*app.Channel2FFTData(2:end-1);
            app.Channel3FFTData = app.Channel3FFTData(1:L/2+1);
            app.Channel3FFTData(2:end-1) = 2*app.Channel3FFTData(2:end-1);
            app.Channel4FFTData = app.Channel4FFTData(1:L/2+1);
            app.Channel4FFTData(2:end-1) = 2*app.Channel4FFTData(2:end-1);
            app.Channel5FFTData = app.Channel5FFTData(1:L/2+1);
            app.Channel5FFTData(2:end-1) = 2*app.Channel5FFTData(2:end-1);
                      
            plot(app.FFTDataChannel1,app.f,app.Channel1FFTData);
            plot(app.FFTDataChannel2,app.f,app.Channel2FFTData);
            plot(app.FFTDataChannel3,app.f,app.Channel3FFTData);
            plot(app.FFTDataChannel4,app.f,app.Channel4FFTData);
            plot(app.FFTDataChannel5,app.f,app.Channel5FFTData);

            if (height(app.SettingsUsed) == 3)
                app.SettingsUsed = [app.SettingsUsed; "Executed Fast Fourrier Transform"];
            end
         
            app.OutlierDeviationOptionDropdown.Enable = 'on';
            app.REMOVEOUTLIERButton.Enable = 'on';

        end

        % Button pushed function: REMOVEOUTLIERButton
        function REMOVEOUTLIERButtonPushed(app, event)


            % CLEAR ALLE PLOTS
            cla(app.OutlierDataChannel1);
            cla(app.OutlierDataChannel2);
            cla(app.OutlierDataChannel3); 
            cla(app.OutlierDataChannel4); 
            cla(app.OutlierDataChannel5); 


            if(app.removeOutlierOption == "grubbs" && (app.thresholdFactor ~= 1))
                uialert(app.UIFigure,'When choosing grubbs option threshgoldFactor must be between 0 and 1','An error occured');
            elseif(app.removeOutlierOption == "gesd" && (app.thresholdFactor ~= 1))
                uialert(app.UIFigure,'When choosing gesd option threshgoldFactor must be between 0 and 1','An error occured');
            else
                Outlier1Channel1 = filloutliers(app.Channel1FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel2 = filloutliers(app.Channel2FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel3 = filloutliers(app.Channel3FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel4 = filloutliers(app.Channel4FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                Outlier1Channel5 = filloutliers(app.Channel5FFTData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
    
                hold(app.OutlierDataChannel1,'on');
                plot(app.OutlierDataChannel1,app.f,app.Channel1FFTData);
                plot(app.OutlierDataChannel1,app.f,Outlier1Channel1,'r-.');
                hold(app.OutlierDataChannel1,'off');
                hold(app.OutlierDataChannel2,'on');
                plot(app.OutlierDataChannel2,app.f,app.Channel2FFTData);
                plot(app.OutlierDataChannel2,app.f,Outlier1Channel2,'r-.');
                hold(app.OutlierDataChannel2,'off');
                hold(app.OutlierDataChannel3,'on');
                plot(app.OutlierDataChannel3,app.f,app.Channel3FFTData);
                plot(app.OutlierDataChannel3,app.f,Outlier1Channel3,'r-.');
                hold(app.OutlierDataChannel3,'off');
                hold(app.OutlierDataChannel4,'on');
                plot(app.OutlierDataChannel4,app.f,app.Channel4FFTData);
                plot(app.OutlierDataChannel4,app.f,Outlier1Channel4,'r-.');
                hold(app.OutlierDataChannel4,'off');
                hold(app.OutlierDataChannel5,'on');
                plot(app.OutlierDataChannel5,app.f,app.Channel5FFTData);
                plot(app.OutlierDataChannel5,app.f,Outlier1Channel5,'r-.');
                hold(app.OutlierDataChannel5,'off');

                if (height(app.SettingsUsed) == 3)
                    app.SettingsUsed = [app.SettingsUsed; ("Removed outliers using " + app.OutlierOptionDropDown.Value + "standard deviation method")];
                end
    
                app.FilterOrderDropdown.Enable = 'on';
                app.FilterTypeDropdown.Enable = 'on';
                app.PROCESSINGTRACKAButton.Enable = 'on';
                app.TrackA_FC1.Enable = 'on';
            end

        end

        % Value changed function: OutlierDeviationOptionDropdown
        function OutlierDeviationOptionDropdownValueChanged(app, event)
            value = app.OutlierDeviationOptionDropdown.Value;
            app.thresholdFactor = str2num(value);
        end

        % Button pushed function: PROCESSINGTRACKBButton
        function PROCESSINGTRACKBButtonPushed(app, event)
            Fs = 100;
            Framelength = 10;
            %ctr1 = (1:1:size(app.rawChannelData,1));
            ch1 = app.rawChannelData(:,1); 
            movrmsWin = dsp.MovingRMS(20);
            movrmsWin_overlap = dsp.MovingRMS(20,15);
            movrmsExp = dsp.MovingRMS('Method','Exponential weighting',...
            'ForgettingFactor',0.995);

            scope  = timescope('SampleRate',[Fs,Fs,Fs/(20-15),Fs],...
                'TimeSpanOverrunAction','Scroll',...
                'TimeSpanSource','Property',...
                'TimeSpan',500,...
                'ShowGrid',true,...
                'YLimits',[-10.0 10]);
            title = 'Moving RMS';
            scope.Title = title;

                for i = 1:size(ch1,1)
                    x = 1 + 0.1 * randn(Framelength,1);
                    y1 = movrmsWin(ch1(i,1));
                    y2 = movrmsWin_overlap(ch1(i,1));
                    y3 = movrmsExp(ch1(i,1));
                    scope(x,y1,y2,y3);
                end
                show(scope);

        end

        % Button pushed function: PROCESSINGTRACKAButton
        function PROCESSINGTRACKAButtonPushed(app, event)

            % CLEAR ALLE PLOTS
            cla(app.TrackAchannel1);
            cla(app.TrackAchannel2);
            cla(app.TrackAchannel3); 
            cla(app.TrackAchannel4); 
            cla(app.TrackAchannel5); 

            nyF = app.fs/2;

            channel1MvData = app.mVDataMatrix(:,1);
            channel2MvData = app.mVDataMatrix(:,2);
            channel3MvData = app.mVDataMatrix(:,3);
            channel4MvData = app.mVDataMatrix(:,4);
            channel5MvData = app.mVDataMatrix(:,5);

            if(isequal(num2str(app.FilterType),'hp') || isequal(num2str(app.FilterType),'lp'))
                    ylp =  filter1(num2str(app.FilterType),channel1MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order', uint16(app.FilterOrder));
                    ylp2 = filter1(num2str(app.FilterType),channel2MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    ylp3 = filter1(num2str(app.FilterType),channel3MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    ylp4 = filter1(num2str(app.FilterType),channel4MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    ylp5 = filter1(num2str(app.FilterType),channel5MvData,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
            elseif(isequal(num2str(app.FilterType),'bp') || isequal(num2str(app.FilterType),'bs'))
                    ylp =  filter1(num2str(app.FilterType),channel1MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order', uint16(app.FilterOrder));
                    ylp2 = filter1(num2str(app.FilterType),channel2MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    ylp3 = filter1(num2str(app.FilterType),channel3MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    ylp4 = filter1(num2str(app.FilterType),channel4MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    ylp5 = filter1(num2str(app.FilterType),channel5MvData,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
            end

%             FULL WAVE RECITIFICATION
                rectYlp1 = abs(ylp);
                rectYlp2 = abs(ylp2);
                rectYlp3 = abs(ylp3);
                rectYlp4 = abs(ylp4);
                rectYlp5 = abs(ylp5);
                
%                 PLOTTING DATA
            hold(app.TrackAchannel1,'on');
            plot(app.TrackAchannel1, abs(channel1MvData));
            plot(app.TrackAchannel1, rectYlp1,'r');
            hold(app.TrackAchannel1,'off');

            hold(app.TrackAchannel2,'on');
            plot(app.TrackAchannel2, abs(channel2MvData));
            plot(app.TrackAchannel2, rectYlp2,'r');
            hold(app.TrackAchannel2,'off');
           
            hold(app.TrackAchannel3,'on');
            plot(app.TrackAchannel3, abs(channel3MvData));
            plot(app.TrackAchannel3, rectYlp3,'r');
            hold(app.TrackAchannel3,'off');

            hold(app.TrackAchannel4,'on');
            plot(app.TrackAchannel4, abs(channel4MvData));
            plot(app.TrackAchannel4, rectYlp4,'r');
            hold(app.TrackAchannel4,'off');

            hold(app.TrackAchannel5,'on');
            plot(app.TrackAchannel5, abs(channel5MvData));
            plot(app.TrackAchannel5, rectYlp5,'r');
            hold(app.TrackAchannel5,'off');

            if (height(app.SettingsUsed) == 4)
                app.SettingsUsed = [app.SettingsUsed; "Processing track A:"; ("\tFilter type: " + app.FilterType); ("\tFilter order: " + app.FilterOrder) ];
            end


        end

        % Value changed function: FilterTypeDropdown
        function FilterTypeDropdownValueChanged(app, event)
            value = app.FilterTypeDropdown.Value;
            if value == "High-pass"
                app.TrackA_FC2.Enable = 'off';
%                 app.TrackA_FC1.Limits = [0 1];
                app.FilterType = 'hp';
            elseif value == "Low-pass"
                app.TrackA_FC2.Enable = 'off';
%                 app.TrackA_FC1.Limits = [0 1];
                app.FilterType = 'lp';
            elseif value == "Band-pass"
                app.TrackA_FC2.Enable = 'on';
                app.TrackA_FC1.Value = 0;
                app.TrackA_FC2.Value = 1;
%                 app.TrackA_FC1.Limits = [0 round(app.TrackA_FC2.Value)];
%                 app.TrackA_FC2.Limits = [round(app.TrackA_FC1.Value) 100];
                app.FilterType = 'bp';
            elseif value == "Bandstop"
                app.TrackA_FC2.Enable = 'on';
                app.TrackA_FC1.Value = 0;
                app.TrackA_FC2.Value = 1;
%                 app.TrackA_FC1.Limits = [0 round(app.TrackA_FC2.Value)];
%                 app.TrackA_FC2.Limits = [round(app.TrackA_FC1.Value) 100];
                app.FilterType = 'bs';
            end

        end

        % Value changed function: FilterOrderDropdown
        function FilterOrderDropdownValueChanged(app, event)
            value = app.FilterOrderDropdown.Value;
            if value == "1"
                app.FilterOrder = 1;
            elseif value == "2"
                app.FilterOrder = 2;
            elseif value == "3"
                app.FilterOrder = 3;
            elseif value == "4"
                app.FilterOrder = 4;
            elseif value == "5"
                app.FilterOrder = 5;
            end
            
        end

        % Value changed function: TrackA_FC2
        function TrackA_FC2ValueChanged(app, event)
            value = app.TrackA_FC2.Value;
            
        end

        % Value changed function: TrackA_FC1
        function TrackA_FC1ValueChanged(app, event)
            value = app.TrackA_FC1.Value;    
        end

        % Button pushed function: EXPORTDATABEFOREMVCButton
        function EXPORTDATABEFOREMVCButtonPushed(app, event)
            
            % CURRENTLY MV DATA.............             
            
            header = ["CH1" "CH2" "CH3" "CH4" "CH5"];
            export = [header; app.mVDataMatrix];
            
            writematrix(export,'export.txt','Delimiter','\t')  
            type export.txt
            
            %second file for steps and settings
            writematrix(app.SettingsUsed,'settings.txt','Delimiter','\t') ; 
            type settings.txt;
        end

        % Value changed function: FillOutlierOptionDropdown
        function FillOutlierOptionDropdownValueChanged(app, event)
            value = app.FillOutlierOptionDropdown.Value;
            app.fillOutlierOption = value;
        end

        % Value changed function: RemoveOutlierOptionDropdown
        function RemoveOutlierOptionDropdownValueChanged(app, event)
            value = app.RemoveOutlierOptionDropdown.Value;
            app.removeOutlierOption = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.READTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.READTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.READTXTFILEButton.Text = 'READ TXT FILE';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'nSeq'; 'DI'; 'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable_2.RowName = {};
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.TEXTFILEDATALabel = uilabel(app.UIFigure);
            app.TEXTFILEDATALabel.Text = 'TEXT  FILE DATA';
            app.mVDATALabel = uilabel(app.UIFigure);
            app.mVDATALabel.Text = 'mV DATA';
            app.TOmVButton = uibutton(app.UIFigure, 'push');
            app.TOmVButton.ButtonPushedFcn = createCallbackFcn(app, @TOmVButtonPushed, true);
            app.TOmVButton.Enable = 'off';
            app.TOmVButton.Text = 'TO mV';
            app.FFTButton = uibutton(app.UIFigure, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Enable = 'off';
            app.FFTButton.Text = 'FFT';
            app.REMOVEOUTLIERButton = uibutton(app.UIFigure, 'push');
            app.REMOVEOUTLIERButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEOUTLIERButtonPushed, true);
            app.REMOVEOUTLIERButton.Enable = 'off';
            app.REMOVEOUTLIERButton.Text = 'REMOVE OUTLIER';
            app.OutlierDeviationOptionDropdown = uidropdown(app.UIFigure);
            app.OutlierDeviationOptionDropdown.Items = {'1', '2', '3'};
            app.OutlierDeviationOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @OutlierDeviationOptionDropdownValueChanged, true);
            app.OutlierDeviationOptionDropdown.Enable = 'off';
            app.OutlierDeviationOptionDropdown.Value = '1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RAWDATATab = uitab(app.TabGroup);
            app.RAWDATATab.Title = 'RAW DATA';
            app.TabGroup2 = uitabgroup(app.RAWDATATab);
            app.CH1Tab = uitab(app.TabGroup2);
            app.CH1Tab.Title = 'CH1';
            app.RawDataChannel1 = uiaxes(app.CH1Tab);
            title(app.RawDataChannel1, 'CHANNEL 1')
            xlabel(app.RawDataChannel1, 'X = nSeq')
            ylabel(app.RawDataChannel1, 'Y')
            zlabel(app.RawDataChannel1, 'Z')
            app.CH2Tab = uitab(app.TabGroup2);
            app.CH2Tab.Title = 'CH2';
            app.RawDataChannel2 = uiaxes(app.CH2Tab);
            title(app.RawDataChannel2, 'CHANNEL 2')
            xlabel(app.RawDataChannel2, 'X = nSeq')
            ylabel(app.RawDataChannel2, 'Y')
            zlabel(app.RawDataChannel2, 'Z')
            app.CH3Tab = uitab(app.TabGroup2);
            app.CH3Tab.Title = 'CH3';
            app.RawDataChannel3 = uiaxes(app.CH3Tab);
            title(app.RawDataChannel3, 'CHANNEL 3')
            xlabel(app.RawDataChannel3, 'X = nSeq')
            ylabel(app.RawDataChannel3, 'Y')
            zlabel(app.RawDataChannel3, 'Z')
            app.CH4Tab = uitab(app.TabGroup2);
            app.CH4Tab.Title = 'CH4';
            app.RawDataChannel4 = uiaxes(app.CH4Tab);
            title(app.RawDataChannel4, 'CHANNEL 4')
            xlabel(app.RawDataChannel4, 'X = nSeq')
            ylabel(app.RawDataChannel4, 'Y')
            zlabel(app.RawDataChannel4, 'Z')
            app.CH5Tab = uitab(app.TabGroup2);
            app.CH5Tab.Title = 'CH5';
            app.RawDataChannel5 = uiaxes(app.CH5Tab);
            title(app.RawDataChannel5, 'CHANNEL 5')
            xlabel(app.RawDataChannel5, 'X = nSeq')
            ylabel(app.RawDataChannel5, 'Y')
            zlabel(app.RawDataChannel5, 'Z')
            app.mVDATATab = uitab(app.TabGroup);
            app.mVDATATab.Title = 'mV DATA';
            app.TabGroup2_2 = uitabgroup(app.mVDATATab);
            app.CH1Tab_2 = uitab(app.TabGroup2_2);
            app.CH1Tab_2.Title = 'CH1';
            app.MvDataChannel1 = uiaxes(app.CH1Tab_2);
            title(app.MvDataChannel1, 'Title')
            xlabel(app.MvDataChannel1, 'X = nSeq')
            ylabel(app.MvDataChannel1, 'Y = mV')
            zlabel(app.MvDataChannel1, 'Z')
            app.CH2Tab_2 = uitab(app.TabGroup2_2);
            app.CH2Tab_2.Title = 'CH2';
            app.MvDataChannel2 = uiaxes(app.CH2Tab_2);
            title(app.MvDataChannel2, 'Title')
            xlabel(app.MvDataChannel2, 'X = nSeq')
            ylabel(app.MvDataChannel2, 'Y = mV')
            zlabel(app.MvDataChannel2, 'Z')
            app.CH3Tab_2 = uitab(app.TabGroup2_2);
            app.CH3Tab_2.Title = 'CH3';
            app.MvDataChannel3 = uiaxes(app.CH3Tab_2);
            title(app.MvDataChannel3, 'Title')
            xlabel(app.MvDataChannel3, 'X = nSeq')
            ylabel(app.MvDataChannel3, 'Y = mV')
            zlabel(app.MvDataChannel3, 'Z')
            app.CH4Tab_2 = uitab(app.TabGroup2_2);
            app.CH4Tab_2.Title = 'CH4';
            app.MvDataChannel4 = uiaxes(app.CH4Tab_2);
            title(app.MvDataChannel4, 'Title')
            xlabel(app.MvDataChannel4, 'X = nSeq')
            ylabel(app.MvDataChannel4, 'Y = mV')
            zlabel(app.MvDataChannel4, 'Z')
            app.CH5Tab_2 = uitab(app.TabGroup2_2);
            app.CH5Tab_2.Title = 'CH5';
            app.MvDataChannel5 = uiaxes(app.CH5Tab_2);
            title(app.MvDataChannel5, 'Title')
            xlabel(app.MvDataChannel5, 'X = nSeq')
            ylabel(app.MvDataChannel5, 'Y = mV')
            zlabel(app.MvDataChannel5, 'Z')
            app.FFTANALYSISTab = uitab(app.TabGroup);
            app.FFTANALYSISTab.Title = 'FFT ANALYSIS';
            app.TabGroup2_3 = uitabgroup(app.FFTANALYSISTab);
            app.CH1Tab_3 = uitab(app.TabGroup2_3);
            app.CH1Tab_3.Title = 'CH1';
            app.FFTDataChannel1 = uiaxes(app.CH1Tab_3);
            title(app.FFTDataChannel1, 'Title')
            xlabel(app.FFTDataChannel1, 'X = Hz')
            ylabel(app.FFTDataChannel1, 'Y = intensity')
            zlabel(app.FFTDataChannel1, 'Z')
            app.CH2Tab_3 = uitab(app.TabGroup2_3);
            app.CH2Tab_3.Title = 'CH2';
            app.FFTDataChannel2 = uiaxes(app.CH2Tab_3);
            title(app.FFTDataChannel2, 'Title')
            xlabel(app.FFTDataChannel2, 'X = Hz')
            ylabel(app.FFTDataChannel2, 'Y = intensity')
            zlabel(app.FFTDataChannel2, 'Z')
            app.CH3Tab_3 = uitab(app.TabGroup2_3);
            app.CH3Tab_3.Title = 'CH3';
            app.FFTDataChannel3 = uiaxes(app.CH3Tab_3);
            title(app.FFTDataChannel3, 'Title')
            xlabel(app.FFTDataChannel3, 'X = Hz')
            ylabel(app.FFTDataChannel3, 'Y = intensity')
            zlabel(app.FFTDataChannel3, 'Z')
            app.CH4Tab_3 = uitab(app.TabGroup2_3);
            app.CH4Tab_3.Title = 'CH4';
            app.FFTDataChannel4 = uiaxes(app.CH4Tab_3);
            title(app.FFTDataChannel4, 'Title')
            xlabel(app.FFTDataChannel4, 'X = Hz')
            ylabel(app.FFTDataChannel4, 'Y = intensity')
            zlabel(app.FFTDataChannel4, 'Z')
            app.CH5Tab_3 = uitab(app.TabGroup2_3);
            app.CH5Tab_3.Title = 'CH5';
            app.FFTDataChannel5 = uiaxes(app.CH5Tab_3);
            title(app.FFTDataChannel5, 'Title')
            xlabel(app.FFTDataChannel5, 'X = Hz')
            ylabel(app.FFTDataChannel5, 'Y = intensity')
            zlabel(app.FFTDataChannel5, 'Z')
            app.REMOVEOUTLIERTab = uitab(app.TabGroup);
            app.REMOVEOUTLIERTab.Title = 'REMOVE OUTLIER';
            app.TabGroup2_4 = uitabgroup(app.REMOVEOUTLIERTab);
            app.CH1Tab_4 = uitab(app.TabGroup2_4);
            app.CH1Tab_4.Title = 'CH1';
            app.OutlierDataChannel1 = uiaxes(app.CH1Tab_4);
            title(app.OutlierDataChannel1, 'Title')
            xlabel(app.OutlierDataChannel1, 'X')
            ylabel(app.OutlierDataChannel1, 'Y')
            zlabel(app.OutlierDataChannel1, 'Z')
            app.CH2Tab_4 = uitab(app.TabGroup2_4);
            app.CH2Tab_4.Title = 'CH2';
            app.OutlierDataChannel2 = uiaxes(app.CH2Tab_4);
            title(app.OutlierDataChannel2, 'Title')
            xlabel(app.OutlierDataChannel2, 'X')
            ylabel(app.OutlierDataChannel2, 'Y')
            zlabel(app.OutlierDataChannel2, 'Z')
            app.CH3Tab_4 = uitab(app.TabGroup2_4);
            app.CH3Tab_4.Title = 'CH3';
            app.OutlierDataChannel3 = uiaxes(app.CH3Tab_4);
            title(app.OutlierDataChannel3, 'Title')
            xlabel(app.OutlierDataChannel3, 'X')
            ylabel(app.OutlierDataChannel3, 'Y')
            zlabel(app.OutlierDataChannel3, 'Z')
            app.CH4Tab_4 = uitab(app.TabGroup2_4);
            app.CH4Tab_4.Title = 'CH4';
            app.OutlierDataChannel4 = uiaxes(app.CH4Tab_4);
            title(app.OutlierDataChannel4, 'Title')
            xlabel(app.OutlierDataChannel4, 'X')
            ylabel(app.OutlierDataChannel4, 'Y')
            zlabel(app.OutlierDataChannel4, 'Z')
            app.CH5Tab_4 = uitab(app.TabGroup2_4);
            app.CH5Tab_4.Title = 'CH5';
            app.OutlierDataChannel5 = uiaxes(app.CH5Tab_4);
            title(app.OutlierDataChannel5, 'Title')
            xlabel(app.OutlierDataChannel5, 'X')
            ylabel(app.OutlierDataChannel5, 'Y')
            zlabel(app.OutlierDataChannel5, 'Z')
            app.PROCESSINGTRACKATab = uitab(app.TabGroup);
            app.PROCESSINGTRACKATab.Title = 'PROCESSING TRACK A';
            app.TabGroup2_5 = uitabgroup(app.PROCESSINGTRACKATab);
            app.CH1Tab_5 = uitab(app.TabGroup2_5);
            app.CH1Tab_5.Title = 'CH1';
            app.TrackAchannel1 = uiaxes(app.CH1Tab_5);
            title(app.TrackAchannel1, 'Title')
            xlabel(app.TrackAchannel1, 'X')
            ylabel(app.TrackAchannel1, 'Y')
            zlabel(app.TrackAchannel1, 'Z')
            app.CH2Tab_5 = uitab(app.TabGroup2_5);
            app.CH2Tab_5.Title = 'CH2';
            app.TrackAchannel2 = uiaxes(app.CH2Tab_5);
            title(app.TrackAchannel2, 'Title')
            xlabel(app.TrackAchannel2, 'X')
            ylabel(app.TrackAchannel2, 'Y')
            zlabel(app.TrackAchannel2, 'Z')
            app.CH3Tab_5 = uitab(app.TabGroup2_5);
            app.CH3Tab_5.Title = 'CH3';
            app.TrackAchannel3 = uiaxes(app.CH3Tab_5);
            title(app.TrackAchannel3, 'Title')
            xlabel(app.TrackAchannel3, 'X')
            ylabel(app.TrackAchannel3, 'Y')
            zlabel(app.TrackAchannel3, 'Z')
            app.CH4Tab_5 = uitab(app.TabGroup2_5);
            app.CH4Tab_5.Title = 'CH4';
            app.TrackAchannel4 = uiaxes(app.CH4Tab_5);
            title(app.TrackAchannel4, 'Title')
            xlabel(app.TrackAchannel4, 'X')
            ylabel(app.TrackAchannel4, 'Y')
            zlabel(app.TrackAchannel4, 'Z')
            app.CH5Tab_5 = uitab(app.TabGroup2_5);
            app.CH5Tab_5.Title = 'CH5';
            app.TrackAchannel5 = uiaxes(app.CH5Tab_5);
            title(app.TrackAchannel5, 'Title')
            xlabel(app.TrackAchannel5, 'X')
            ylabel(app.TrackAchannel5, 'Y')
            zlabel(app.TrackAchannel5, 'Z')
            app.PROCESSINGTRACKBTab = uitab(app.TabGroup);
            app.PROCESSINGTRACKBTab.Title = 'PROCESSING TRACK B';
            app.MVCNORMALISATIONTab = uitab(app.TabGroup);
            app.MVCNORMALISATIONTab.Title = 'MVC NORMALISATION';
            app.PROCESSINGTRACKAButton = uibutton(app.UIFigure, 'push');
            app.PROCESSINGTRACKAButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSINGTRACKAButtonPushed, true);
            app.PROCESSINGTRACKAButton.Enable = 'off';
            app.PROCESSINGTRACKAButton.Text = 'PROCESSING TRACK A';
            app.PROCESSINGTRACKBButton = uibutton(app.UIFigure, 'push');
            app.PROCESSINGTRACKBButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSINGTRACKBButtonPushed, true);
            app.PROCESSINGTRACKBButton.Enable = 'off';
            app.PROCESSINGTRACKBButton.Text = 'PROCESSING TRACK B';
            app.MVCNORMALISATIONButton = uibutton(app.UIFigure, 'push');
            app.MVCNORMALISATIONButton.Enable = 'off';
            app.MVCNORMALISATIONButton.Text = 'MVC NORMALISATION';
            app.EXPORTDATAAFTERMVCButton = uibutton(app.UIFigure, 'push');
            app.EXPORTDATAAFTERMVCButton.Enable = 'off';
            app.EXPORTDATAAFTERMVCButton.Text = 'EXPORT DATA AFTER MVC';
            app.FilterTypeDropdown = uidropdown(app.UIFigure);
            app.FilterTypeDropdown.Items = {'High-pass', 'Low-pass', 'Band-pass', 'Bandstop'};
            app.FilterTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropdownValueChanged, true);
            app.FilterTypeDropdown.Enable = 'off';
            app.FilterTypeDropdown.Placeholder = 'Select filter';
            app.FilterTypeDropdown.Value = 'High-pass';
            app.FilterOrderDropdown = uidropdown(app.UIFigure);
            app.FilterOrderDropdown.Items = {'1', '2', '3', '4', '5'};
            app.FilterOrderDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterOrderDropdownValueChanged, true);
            app.FilterOrderDropdown.Enable = 'off';
            app.FilterOrderDropdown.Value = '1';
            app.TrackA_FC2 = uieditfield(app.UIFigure, 'numeric');
            app.TrackA_FC2.Limits = [0 500];
            app.TrackA_FC2.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC2ValueChanged, true);
            app.TrackA_FC2.Enable = 'off';
            app.TrackA_FC1 = uieditfield(app.UIFigure, 'numeric');
            app.TrackA_FC1.Limits = [0 500];
            app.TrackA_FC1.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC1ValueChanged, true);
            app.TrackA_FC1.Enable = 'off';
            app.EXPORTDATABEFOREMVCButton = uibutton(app.UIFigure, 'push');
            app.EXPORTDATABEFOREMVCButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTDATABEFOREMVCButtonPushed, true);
            app.EXPORTDATABEFOREMVCButton.Text = 'EXPORT DATA BEFORE MVC';
            app.FillOutlierOptionDropdown = uidropdown(app.UIFigure);
            app.FillOutlierOptionDropdown.Items = {'center', 'clip', 'previous', 'next', 'nearest', 'linear', 'spline', 'pchip', 'makima'};
            app.FillOutlierOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @FillOutlierOptionDropdownValueChanged, true);
            app.FillOutlierOptionDropdown.Value = 'center';
            app.RemoveOutlierOptionDropdown = uidropdown(app.UIFigure);
            app.RemoveOutlierOptionDropdown.Items = {'median', 'mean', 'quartiles', 'grubbs', 'gesd'};
            app.RemoveOutlierOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @RemoveOutlierOptionDropdownValueChanged, true);
            app.RemoveOutlierOptionDropdown.Value = 'median';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end